classdef FuzzyCPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        POSSGENUIFigure                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        CreditsMenu                    matlab.ui.container.Menu
        SamplenumberEditField          matlab.ui.control.NumericEditField
        SamplenumberEditFieldLabel     matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        Step1Panel                     matlab.ui.container.Panel
        LoadNominalValuesButton        matlab.ui.control.Button
        Step4DesignDistributionPanel   matlab.ui.container.Panel
        CheckBox                       matlab.ui.control.CheckBox
        GenerateButton                 matlab.ui.control.Button
        COVEditField                   matlab.ui.control.NumericEditField
        COVEditFieldLabel              matlab.ui.control.Label
        SamplesEditField               matlab.ui.control.NumericEditField
        SamplesEditFieldLabel          matlab.ui.control.Label
        MeasurementErrorLabel          matlab.ui.control.Label
        Step2SoilProfilePanel          matlab.ui.container.Panel
        DeleteButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        SoilTypeDropDown               matlab.ui.control.DropDown
        SoilTypeDropDownLabel          matlab.ui.control.Label
        LayerThicknessmEditField       matlab.ui.control.NumericEditField
        LayerThicknessmEditFieldLabel  matlab.ui.control.Label
        Step3DatabaseSelectionPanel    matlab.ui.container.Panel
        ExtractDataButton              matlab.ui.control.Button
        Tree3                          matlab.ui.container.CheckBoxTree
        UIMF_3                         matlab.ui.control.UIAxes
        UIMF_2                         matlab.ui.control.UIAxes
        UISoilP                        matlab.ui.control.UIAxes
        UIMF                           matlab.ui.control.UIAxes
        UIDATA                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        thickVect=[] % Vector of soil layer thicknesses
        soilIndexVect=[] %Vector of soil types
        soilname={} %vector of soil names
        soilInd=1; %counter for soil tree
        dataType; %list of databases for checked nodes
        SoilDATASET=[]; %collection database for investigated profile
        zRef; %reference z axis
        qtRES; %reference qt
        dz=0.01; %mesh size
        zSoil %soil z vector
        ACFoption = 1; %if 1 use ACF transformation, if not 1 use DAATransform
        nAlpha = 21; %1001
        ncl=[ 3 6 9 ]; %subclasses
        mPar=2.0;
        nclmin=15; %min number of sample for fuzzy clustering
        cluCPT; %container for partitions
        DeMF_Qt; %design MFs
        DialogApp % Dialog box app
    end
    
    methods (Access = private)
        
        function DatabaseRead(app)
            nodeCk=app.Tree3.CheckedNodes;
            nI=length(nodeCk);
            zdept=cumsum([0 app.thickVect]);
            app.zSoil=0:app.dz:zdept(end);
            nz=length(app.zSoil);
            if nI==0
                uialert(app.POSSGENUIFigure,'0 Database Selected','Info','Icon','info')
            else
                for hh=1:nI
                    laynum=nodeCk(hh).NodeData;
                    if laynum > 0
                        zz=zdept(laynum):app.dz:zdept(laynum+1);
                        switch nodeCk(hh).Text
                            case 'CPT_3_1196 SAND'
                                filename='Global-CPT31196_SAND.mat';
                                filetype=1;
                                flag=0;
                            case 'BGS River Terrace'
                                filename='BGS_CPT_RiverTerrace.mat';
                                filetype=0;
                                flag=0;
                            case 'CPT_3_1196 SAND MIX'
                                filename='Global-CPT31196_MixtureSAND.mat';
                                filetype=1;
                                flag=0;
                            case 'BGS_OXFORD CLAY' 
                                filename='BGS_CPT_OXC.mat';  
                                filetype=0;
                                flag=0;
                            case 'CPT_3_1196 CLAY'
                                filename='Global-CPT31196_CLAY.mat';   
                                filetype=1;
                                flag=0;
                            case 'VOID'
                                flag=1;
                            otherwise
                                uialert(app.POSSGENUIFigure,'Incorrect Database Selected','Info','Icon','info')
                                flag=2;
                        end
                        if flag==0
                            [Mqt] = dataRead(filename,filetype,zz);
                            if isempty(Mqt)
                                uialert(app.POSSGENUIFigure,'No matching data was found for the selected soil profile','Please check your inputs and retry','Icon','warning')
                            else
                                [n,m]=size(Mqt);
                                app.SoilDATASET=[app.SoilDATASET [Mqt; zeros(nz-n,m)]];
                            end
                        elseif flag == 1
                            % zz=zdept(laynum):app.dz:zdept(laynum+1)+zdept(laynum);
                            app.SoilDATASET=[app.SoilDATASET zeros(nz,1)];
                        else
                            %do nothing
                        end
                    end
                end
            end
        end

        function DataSelection(app)
            soiltype=app.SoilTypeDropDown.ValueIndex;
            switch soiltype
                case 1 %SAND
                    app.dataType={'SAND','CPT_3_1196 SAND'};
                case 2% %SAND mixture
                    app.dataType={'SAND mixture','BGS River Terrace','CPT_3_1196 SAND MIX'};
                case 3 %CLAY
                    app.dataType={'CLAY','BGS_OXFORD CLAY','CPT_3_1196 CLAY'};
                otherwise
                    app.dataType={'NO SOIL','VOID'};
            end
        end
        
        function FZClust(app)
        % fuzzy clusterization of the database - Ruspini partion
        zd=app.zSoil;
        MQTboot=app.SoilDATASET;
        for hh=1:length(zd)
            Qtp=MQTboot(hh,:);
            for kk=1:length(app.ncl)
                QtpUn=nonzeros(Qtp);
                if  length(QtpUn) < app.nclmin %% FUZZY CLUSTERING DOES NOT WORK WITH SINGLETON (THIS AVOID THAT)
                    cluOut{kk}=[min(QtpUn) max(QtpUn)] ; %singleton / fuzzy interval
                else
                     nclkk = app.ncl(kk);   
                     cluOutt=FuzzyClustering(QtpUn,app.ncl(kk),app.mPar,app.nAlpha,app.ACFoption,0,1);
                     cluOut{kk}=cluOutt;
                end
            %script_plotting %uncomment to plot
            end
            app.cluCPT{hh}=cluOut; %fuzzy CPT functions
        end
        end


        function CluPlot(app)
            zd=app.zSoil;
            %LOW
            hold(app.UIMF,'on')
            MF=app.DeMF_Qt{1};
            plot(app.UIMF,MF(:,1),-zd,'k')
            plot(app.UIMF,MF(:,round(app.nAlpha/4,0)),-zd,'g','LineWidth',1)
            plot(app.UIMF,MF(:,round(app.nAlpha/2,0)),-zd,'b','LineWidth',1)
            plot(app.UIMF,MF(:,round(app.nAlpha/4*3,0)),-zd,'m','LineWidth',1)
            plot(app.UIMF,MF(:,end),-zd,'k')
            plot(app.UIMF,MF(:,end-round(app.nAlpha/4,0)+1),-zd,'g','LineWidth',1)
            plot(app.UIMF,MF(:,end-round(app.nAlpha/2,0)+1),-zd,'b','LineWidth',1)
            plot(app.UIMF,MF(:,end-round(app.nAlpha/4*3,0)+1),-zd,'m','LineWidth',1)
            plot(app.UIMF,MF(:,app.nAlpha),-zd,'r','LineWidth',2)
            legend(app.UIMF,'$\alpha =0$','$\alpha =0.25$','$\alpha =0.5$','$\alpha =0.75$','','','','','$\alpha =1$','Interpreter','latex','fontsize',14,'Location','southeast')
            %Typical
            hold(app.UIMF_2,'on')
            MF=app.DeMF_Qt{2};
            plot(app.UIMF_2,MF(:,1),-zd,'k')
            plot(app.UIMF_2,MF(:,round(app.nAlpha/4,0)),-zd,'g','LineWidth',1)
            plot(app.UIMF_2,MF(:,round(app.nAlpha/2,0)),-zd,'b','LineWidth',1)
            plot(app.UIMF_2,MF(:,round(app.nAlpha/4*3,0)),-zd,'m','LineWidth',1)
            plot(app.UIMF_2,MF(:,end),-zd,'k')
            plot(app.UIMF_2,MF(:,end-round(app.nAlpha/4,0)+1),-zd,'g','LineWidth',1)
            plot(app.UIMF_2,MF(:,end-round(app.nAlpha/2,0)+1),-zd,'b','LineWidth',1)
            plot(app.UIMF_2,MF(:,end-round(app.nAlpha/4*3,0)+1),-zd,'m','LineWidth',1)
            plot(app.UIMF_2,MF(:,app.nAlpha),-zd,'r','LineWidth',2)
            legend(app.UIMF_2,'$\alpha =0$','$\alpha =0.25$','$\alpha =0.5$','$\alpha =0.75$','','','','','$\alpha =1$','Interpreter','latex','fontsize',14,'Location','southeast')
            %High
            hold(app.UIMF_3,'on')
            MF=app.DeMF_Qt{3};
            plot(app.UIMF_3,MF(:,1),-zd,'k')
            plot(app.UIMF_3,MF(:,round(app.nAlpha/4,0)),-zd,'g','LineWidth',1)
            plot(app.UIMF_3,MF(:,round(app.nAlpha/2,0)),-zd,'b','LineWidth',1)
            plot(app.UIMF_3,MF(:,round(app.nAlpha/4*3,0)),-zd,'m','LineWidth',1)
            plot(app.UIMF_3,MF(:,end),-zd,'k')
            plot(app.UIMF_3,MF(:,end-round(app.nAlpha/4,0)+1),-zd,'g','LineWidth',1)
            plot(app.UIMF_3,MF(:,end-round(app.nAlpha/2,0)+1),-zd,'b','LineWidth',1)
            plot(app.UIMF_3,MF(:,end-round(app.nAlpha/4*3,0)+1),-zd,'m','LineWidth',1)
            plot(app.UIMF_3,MF(:,app.nAlpha),-zd,'r','LineWidth',2)
            legend(app.UIMF_3,'$\alpha =0$','$\alpha =0.25$','$\alpha =0.5$','$\alpha =0.75$','','','','','$\alpha =1$','Interpreter','latex','fontsize',14,'Location','southeast')
        end

        
        function Collocation(app)
            zd=app.zSoil;
            for kk=1:length(app.ncl)
                DeMF_Qtmax=zeros(length(zd),app.nAlpha);
                DeMF_Qtmin=zeros(length(zd),app.nAlpha);
                for hh=1:length(zd)
                qtiREF=app.qtRES(hh);
                cluOuti=app.cluCPT{hh}{kk};
                    if isstruct(cluOuti) == 0 %if it was a singleton
                         QtUm=qtiREF.*ones(1,app.nAlpha);
                         QtUp=qtiREF.*ones(1,app.nAlpha);
                         DeMF_Qtmin(hh,:)= QtUm;
                         DeMF_Qtmax(hh,:)= QtUp;
                    else
                        Out = cluFuzzyMembership(qtiREF,cluOuti);
                         QtUm = Out.phiUm;
                         QtUp = Out.phiUp;
                         DeMF_Qtmin(hh,:)= QtUm;
                         DeMF_Qtmax(hh,:)= QtUp;
                    end
                end
                app.DeMF_Qt{kk}=[DeMF_Qtmin fliplr(DeMF_Qtmax)];
            end            
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            thick=app.LayerThicknessmEditField.Value;
            app.thickVect=[app.thickVect thick];
            app.soilname=[app.soilname; app.SoilTypeDropDown.Value];
            app.soilIndexVect=[app.soilIndexVect app.SoilTypeDropDown.ValueIndex];
            app.DataSelection
            category1=uitreenode(app.Tree3,'Text',app.dataType{1},'NodeData',0);
            for jj=2:length(app.dataType)
                category2(jj-1)=uitreenode(category1,'Text',app.dataType{jj},'NodeData',app.soilInd);
            end
            bar(app.UISoilP,1,-app.thickVect,'stacked')
            legend(app.UISoilP,app.soilname)
            app.soilInd=app.soilInd+1;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            item=app.Tree3.Children(end);
            app.thickVect(end)=[];
            app.soilIndexVect(end)=[];
            app.soilname(end)=[];
            delete(item)
            bar(app.UISoilP,1,-app.thickVect,'stacked')
            legend(app.UISoilP,app.soilname)
            app.soilInd=app.soilInd-1;

        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                app.SamplesEditField.Enable="on";
                app.COVEditField.Enable="on";
            else
                app.SamplesEditField.Enable="off";
                app.COVEditField.Enable="off";
                app.COVEditField.Value=0;
                app.SamplesEditField.Value=0;
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            d = uiprogressdlg(app.POSSGENUIFigure,'Title','Please Wait',...
        'Message','Starting');
            flagRND=app.CheckBox.Value;
            %bootstrap expansion
            Mqt=app.SoilDATASET;
            if flagRND == 1
                d.Value = .15; 
                d.Message = 'Random Generation';
                [mz,nsamp]=size(Mqt);
                MQTboot=[];
                nS=app.SamplesEditField.Value;
                for hh=1:nsamp
                    ind=Mqt(:,hh)==0;
                    RF = Mqt(:,hh).*(1 + randn(mz,nS)*app.COVEditField.Value); %random generation
                    RF(ind,:)=0;
                    MQTboot=[MQTboot Mqt(:,hh) RF];
                end
            else
                MQTboot=Mqt;
            end
            d.Value = .30; 
            d.Message = 'Dataset Creation';
            app.SoilDATASET=MQTboot;
            cla(app.UIDATA,"reset")
            plot(app.UIDATA, app.SoilDATASET,-app.zSoil,'Color',[.95 .95 .95]);
            hold(app.UIDATA,"on")
            plot(app.UIDATA, Mqt,-app.zSoil,'Color','k');
            plot(app.UIDATA, app.qtRES,-app.zRef,'r','LineWidth',1)
            xlabel(app.UIDATA, {'$qt [N/m^2]$'},'Interpreter','latex');
            [m,n]=size(app.SoilDATASET);
            app.SamplenumberEditField.Value=n;
            d.Value = .50; 
            d.Message = 'Data Clustering';
            app.FZClust %step 1 clusterization
            d.Value = .75; 
            d.Message = 'Clustering Complete';
            app.Collocation %step 2 collocation
            app.CluPlot
            d.Value = .75; 
            d.Message = 'Generatiom Complete';
            app.SaveButton.Enable="on";
        end

        % Button pushed function: ExtractDataButton
        function ExtractDataButtonPushed(app, event)
            app.SoilDATASET=[];
            app.DatabaseRead
           if app.zRef(end)<app.zSoil(end)
                uialert(app.POSSGENUIFigure,'Reference CPT is shorter than Soil Deposit','Reference CPT - correction','Icon','info')
                app.zRef=app.zSoil;
                nt=length(app.zSoil)-length(app.zRef);
                app.qtRES=[app.qtRES;zeros(nt,1)];
                uialert(app.POSSGENUIFigure,'Done','Success','Icon','success')
                plot(app.UIDATA, app.SoilDATASET,-app.zSoil,'Color','k');
                hold(app.UIDATA,"on")
                plot(app.UIDATA, app.qtRES,-app.zRef,'r','LineWidth',1)
                xlabel(app.UIDATA, {'$qt [N/m^2]$'},'Interpreter','latex');
                [m,n]=size(app.SoilDATASET);
                app.SamplenumberEditField.Value=n;
                app.GenerateButton.Enable="on";
           elseif app.zRef(end)>app.zSoil(end)
                uialert(app.POSSGENUIFigure,'Reference CPT is longer than Soil Deposit','Analysis cannot be performed','Icon','error')
                uialert(app.POSSGENUIFigure,'Increse Soil Deposit Thickness','Reference CPT - correction','Icon','info')
                app.GenerateButton.Enable="off";
           else
                uialert(app.POSSGENUIFigure,'Done','Success','Icon','success')
                plot(app.UIDATA, app.SoilDATASET,-app.zSoil,'Color','k');
                hold(app.UIDATA,"on")
                plot(app.UIDATA, app.qtRES,-app.zRef,'r','LineWidth',1)
                xlabel(app.UIDATA, {'$qt [N/m^2]$'},'Interpreter','latex');
                [m,n]=size(app.SoilDATASET);
                app.SamplenumberEditField.Value=n;
                app.GenerateButton.Enable="on";
           end

        end

        % Button pushed function: LoadNominalValuesButton
        function LoadNominalValuesButtonPushed(app, event)
            [file,location] = uigetfile('*.txt;*.dat');
            figure(app.POSSGENUIFigure);
            if isequal(file,0)
               uialert(app.POSSGENUIFigure,'File Not Loaded','Select File','Icon','warning')
            else
               uialert(app.POSSGENUIFigure,'File Loaded','Select File','Icon','success')
               AA=readmatrix(fullfile(location,file));
               zz=0:app.dz:AA(end,1);
               zd=AA(:,1);
               qti=AA(:,2);
               if length(zd) ~= length(unique(zd))
                    [au,ia] = unique(zd,'stable');
                    zd=zd(ia);
                    qti=qti(ia);
               end
            qti=interp1(zd,qti,zz.',"nearest");
            qti(isnan(qti))=0;
            end
            app.zRef=zz.';
            app.qtRES=qti;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        app.SaveButton.Enable = "off";
        alpha=linspace(0,1,app.nAlpha);
        app.DialogApp = POSSGENRES(app,alpha,app.DeMF_Qt,app.zSoil);

        end

        % Close request function: POSSGENUIFigure
        function POSSGENUIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)  
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            delete(app.DialogApp)
            delete(app)  
            FuzzyCPT;
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            delete(app.DialogApp)
            delete(app)  
        end

        % Menu selected function: CreditsMenu
        function CreditsMenuSelected(app, event)
            uialert(app.POSSGENUIFigure,'<em> Created by </em>: <br>  Dr Alessandro Tombari and Prof. Luciano Stefanini  </br>  <br> <em> Cite as: </em> <br> <blockquote> A. Tombari, M. Dobbs, L. M. J. Holland, and L. Stefanini, A rigorous possibility approach for the geotechnical reliability assessment supported by external database and local experience’, Computers and Geotechnics, vol. 166, p. 105967, Feb. 2024, </blockquote>  doi: <a href="https://doi.org/10.1016/j.compgeo.2023.105967">10.1016/j.compgeo.2023.105967</a>','Credits','Icon','info',Interpreter='html')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.POSSGENUIFigure = uifigure('Visible', 'off');
            app.POSSGENUIFigure.Name = 'POSSGEN';
            app.POSSGENUIFigure.Icon = fullfile(pathToMLAPP, 'logo2.jpg');
            app.POSSGENUIFigure.CloseRequestFcn = createCallbackFcn(app, @POSSGENUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.POSSGENUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Accelerator = 'Q';
            app.CloseMenu.Text = 'Close';
            app.CreditsMenu = uimenu(app.POSSGENUIFigure);
            app.CreditsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreditsMenuSelected, true);
            app.CreditsMenu.Text = 'Credits';
            app.UIDATA = uiaxes(app.POSSGENUIFigure);
            title(app.UIDATA, 'CPT Data')
            xlabel(app.UIDATA, 'Corrected Cone Resistance')
            ylabel(app.UIDATA, 'Z [m]')
            zlabel(app.UIDATA, 'Z')
            app.UIMF = uiaxes(app.POSSGENUIFigure);
            title(app.UIMF, 'LOW DoU')
            xlabel(app.UIMF, 'Corrected Cone Resistance')
            ylabel(app.UIMF, 'Z [m]')
            zlabel(app.UIMF, 'Z')
            app.UISoilP = uiaxes(app.POSSGENUIFigure);
            title(app.UISoilP, 'Soil Profile')
            ylabel(app.UISoilP, 'Z [m]')
            zlabel(app.UISoilP, 'Z')
            app.UISoilP.XLim = [0 2];
            app.UISoilP.XTick = 1;
            app.UISoilP.XTickLabel = {'Profile'};
            app.UIMF_2 = uiaxes(app.POSSGENUIFigure);
            title(app.UIMF_2, 'Typical DoU')
            xlabel(app.UIMF_2, 'Corrected Cone Resistance')
            ylabel(app.UIMF_2, 'Z [m]')
            zlabel(app.UIMF_2, 'Z')
            app.UIMF_3 = uiaxes(app.POSSGENUIFigure);
            title(app.UIMF_3, 'High DoU')
            xlabel(app.UIMF_3, 'Corrected Cone Resistance')
            ylabel(app.UIMF_3, 'Z [m]')
            zlabel(app.UIMF_3, 'Z')
            app.Step3DatabaseSelectionPanel = uipanel(app.POSSGENUIFigure);
            app.Step3DatabaseSelectionPanel.Title = 'Step 3: Database Selection';
            app.Tree3 = uitree(app.Step3DatabaseSelectionPanel, 'checkbox');
            app.ExtractDataButton = uibutton(app.Step3DatabaseSelectionPanel, 'push');
            app.ExtractDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractDataButtonPushed, true);
            app.ExtractDataButton.Text = 'Extract Data';
            app.Step2SoilProfilePanel = uipanel(app.POSSGENUIFigure);
            app.Step2SoilProfilePanel.Title = 'Step 2: Soil Profile';
            app.LayerThicknessmEditFieldLabel = uilabel(app.Step2SoilProfilePanel);
            app.LayerThicknessmEditFieldLabel.Text = 'Layer Thickness [m]';
            app.LayerThicknessmEditField = uieditfield(app.Step2SoilProfilePanel, 'numeric');
            app.LayerThicknessmEditField.Limits = [1 Inf];
            app.LayerThicknessmEditField.Value = 1;
            app.SoilTypeDropDownLabel = uilabel(app.Step2SoilProfilePanel);
            app.SoilTypeDropDownLabel.Text = 'Soil Type';
            app.SoilTypeDropDown = uidropdown(app.Step2SoilProfilePanel);
            app.SoilTypeDropDown.Items = {'Sand', 'Sand (mixed)', 'Oxford Clay', 'No Soil'};
            app.SoilTypeDropDown.Value = 'Sand';
            app.AddButton = uibutton(app.Step2SoilProfilePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.Step2SoilProfilePanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Tooltip = {'Delete Last Entry'};
            app.DeleteButton.Text = 'Delete';
            app.Step4DesignDistributionPanel = uipanel(app.POSSGENUIFigure);
            app.Step4DesignDistributionPanel.Title = 'Step 4: Design Distribution';
            app.MeasurementErrorLabel = uilabel(app.Step4DesignDistributionPanel);
            app.MeasurementErrorLabel.Text = 'Measurement Error';
            app.SamplesEditFieldLabel = uilabel(app.Step4DesignDistributionPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.Step4DesignDistributionPanel, 'numeric');
            app.SamplesEditField.Limits = [0 100];
            app.SamplesEditField.RoundFractionalValues = 'on';
            app.SamplesEditField.Enable = 'off';
            app.COVEditFieldLabel = uilabel(app.Step4DesignDistributionPanel);
            app.COVEditFieldLabel.Text = 'COV';
            app.COVEditField = uieditfield(app.Step4DesignDistributionPanel, 'numeric');
            app.COVEditField.Limits = [0 1];
            app.COVEditField.Enable = 'off';
            app.GenerateButton = uibutton(app.Step4DesignDistributionPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.CheckBox = uicheckbox(app.Step4DesignDistributionPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.Step1Panel = uipanel(app.POSSGENUIFigure);
            app.Step1Panel.Title = 'Step 1';
            app.LoadNominalValuesButton = uibutton(app.Step1Panel, 'push');
            app.LoadNominalValuesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNominalValuesButtonPushed, true);
            app.LoadNominalValuesButton.Tooltip = {'Clolumn 1: z[m]'; 'Column 2: correct cone resistance  [Pa]'};
            app.LoadNominalValuesButton.Text = 'Load Nominal Values';
            app.SaveButton = uibutton(app.POSSGENUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.SamplenumberEditFieldLabel = uilabel(app.POSSGENUIFigure);
            app.SamplenumberEditFieldLabel.Text = 'Sample number:';
            app.SamplenumberEditField = uieditfield(app.POSSGENUIFigure, 'numeric');
            app.SamplenumberEditField.Editable = 'off';
            % Show the figure after all components are created
            app.POSSGENUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FuzzyCPT

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.POSSGENUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.POSSGENUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.POSSGENUIFigure)
        end
    end
end