classdef POSSGENRES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        POSSGENUIFigure  matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        SaveMenu         matlab.ui.container.Menu
        CloseMenu        matlab.ui.container.Menu
        CreditsMenu      matlab.ui.container.Menu
        TabGroup         matlab.ui.container.TabGroup
        LOWTab           matlab.ui.container.Tab
        zmLabel          matlab.ui.control.Label
        alphaLabel       matlab.ui.control.Label
        UITableLOW       matlab.ui.control.Table
        TYPICALTab       matlab.ui.container.Tab
        zmLabel_2        matlab.ui.control.Label
        alphaLabel_2     matlab.ui.control.Label
        UITableTYP       matlab.ui.control.Table
        HIGHTab          matlab.ui.container.Tab
        zmLabel_3        matlab.ui.control.Label
        alphaLabel_3     matlab.ui.control.Label
        UITableHIGH      matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp % Main app
        nz % soil number of depths 
        nAlpha % alpha number of level cuts
        MF_QT %output

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, alpha, MFD, zSoil)
            app.MainApp = mainapp;
            app.MF_QT=MFD;
            app.nz=length(zSoil);
            app.nAlpha=length(alpha);
            app.UITableLOW.Data=MFD{1};
            app.UITableLOW.RowName=num2cell(zSoil)';
            apt=[alpha fliplr(alpha)];
            app.UITableLOW.ColumnName=num2cell(apt);
            %----------------------------------------
            app.UITableTYP.Data=MFD{2};
            app.UITableTYP.RowName=num2cell(zSoil)';
            apt=[alpha fliplr(alpha)];
            app.UITableTYP.ColumnName=num2cell(apt);
            %----------------------------------------
            app.UITableHIGH.Data=MFD{3};
            app.UITableHIGH.RowName=num2cell(zSoil)';
            apt=[alpha fliplr(alpha)];
            app.UITableHIGH.ColumnName=num2cell(apt);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
        if isvalid(app.MainApp)
            app.MainApp.SaveButton.Enable = "on";
        end
            % Delete the dialog box 
        delete(app)    
        end

        % Close request function: POSSGENUIFigure
        function POSSGENUIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SaveButton.Enable = "on";
            end
            delete(app)
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
         filter = {'*.txt';'*.xlsx'};
        [file,location,indx] = uiputfile(filter,'Save Design Possibility Distributions to File','Design_MF');
        MF1=app.MF_QT{1};
        MF2=app.MF_QT{2};
        MF3=app.MF_QT{3};
        if indx == 1
            writematrix(MF1,fullfile(location,strcat(file(1:end-4),'_LOW.txt')),'Delimiter','tab');
            writematrix(MF2,fullfile(location,strcat(file(1:end-4),'_TYP.txt')),'Delimiter','tab');
            writematrix(MF3,fullfile(location,strcat(file(1:end-4),'_HIGH.txt')),'Delimiter','tab');
        elseif indx ==2
            writematrix(MF1,fullfile(location,file),'Sheet','LOW');
            writematrix(MF2,fullfile(location,file),'Sheet','TYPICAL');
            writematrix(MF3,fullfile(location,file),'Sheet','HIGH');
        end
        end

        % Menu selected function: CreditsMenu
        function CreditsMenuSelected(app, event)
                        uialert(app.POSSGENUIFigure,'<em> Created by </em>: <br>  Dr Alessandro Tombari and Prof. Luciano Stefanini  </br>  <br> <em> Cite as: </em> <br> <blockquote> A. Tombari, M. Dobbs, L. M. J. Holland, and L. Stefanini, A rigorous possibility approach for the geotechnical reliability assessment supported by external database and local experience’, Computers and Geotechnics, vol. 166, p. 105967, Feb. 2024, </blockquote>  doi: <a href="https://doi.org/10.1016/j.compgeo.2023.105967">10.1016/j.compgeo.2023.105967</a>','Credits','Icon','info',Interpreter='html')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.POSSGENUIFigure = uifigure('Visible', 'off');
            app.POSSGENUIFigure.Name = 'POSSGEN';
            app.POSSGENUIFigure.CloseRequestFcn = createCallbackFcn(app, @POSSGENUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.POSSGENUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Accelerator = 'Q';
            app.CloseMenu.Text = 'Close';
            app.CreditsMenu = uimenu(app.POSSGENUIFigure);
            app.CreditsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreditsMenuSelected, true);
            app.CreditsMenu.Text = 'Credits';
            app.TabGroup = uitabgroup(app.POSSGENUIFigure);
            app.LOWTab = uitab(app.TabGroup);
            app.LOWTab.Title = 'LOW';
            app.UITableLOW = uitable(app.LOWTab);
            app.UITableLOW.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableLOW.RowName = {'z1'};
            app.alphaLabel = uilabel(app.LOWTab);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$\alpha -->$ ';
            app.zmLabel = uilabel(app.LOWTab);
            app.zmLabel.Interpreter = 'latex';
            app.zmLabel.Text = '$z [m]$';
            app.TYPICALTab = uitab(app.TabGroup);
            app.TYPICALTab.Title = 'TYPICAL';
            app.UITableTYP = uitable(app.TYPICALTab);
            app.UITableTYP.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableTYP.RowName = {'z1'};
            app.alphaLabel_2 = uilabel(app.TYPICALTab);
            app.alphaLabel_2.Interpreter = 'latex';
            app.alphaLabel_2.Text = '$\alpha -->$ ';
            app.zmLabel_2 = uilabel(app.TYPICALTab);
            app.zmLabel_2.Interpreter = 'latex';
            app.zmLabel_2.Text = '$z [m]$';
            app.HIGHTab = uitab(app.TabGroup);
            app.HIGHTab.Title = 'HIGH';
            app.UITableHIGH = uitable(app.HIGHTab);
            app.UITableHIGH.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableHIGH.RowName = {'z1'};
            app.alphaLabel_3 = uilabel(app.HIGHTab);
            app.alphaLabel_3.Interpreter = 'latex';
            app.alphaLabel_3.Text = '$\alpha -->$ ';
            app.zmLabel_3 = uilabel(app.HIGHTab);
            app.zmLabel_3.Interpreter = 'latex';
            app.zmLabel_3.Text = '$z [m]$';
            % Show the figure after all components are created
            app.POSSGENUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POSSGENRES(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.POSSGENUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.POSSGENUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.POSSGENUIFigure)
        end
    end
end